classdef POM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        NextButton        matlab.ui.control.Button
        PreviousButton    matlab.ui.control.Button
        LoadimagesButton  matlab.ui.control.Button
        PathLabel         matlab.ui.control.Label
        Label             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimagesButton
        function LoadimagesButtonPushed(app, event)
            global myFolder;
            myFolder = uigetdir('E:\studia\VI sem\Moje\POM\MR-głowa\STU0001\SER0007');%('C:\', 'Choose directory')                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isdir(myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(myFolder, '*.dcm'); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen


global theFiles;
theFiles = dir(filePattern);

% for k = 1: length(theFiles)
%   baseFileName = theFiles(k).name;
%   fullFileName = fullfile(myFolder, baseFileName);
% end

  file1 = fullfile(myFolder, theFiles(1).name);
  info = dicominfo(file1);  
  I = dicomread(info);
  imshow(I,[],'Parent', app.UIAxes);
    
  
  drawnow; % Force display to update immediately.
  
  global currentFileNumber;
  global numberOfFiles;
  
  numberOfFiles = length(theFiles);
  currentFileNumber = 1;
  app.PathLabel.Text = myFolder;
  
  formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,currentFileNumber,length(theFiles));
  app.Label.Visible = 'on';
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %Show previous image
            global currentFileNumber;
            global numberOfFiles;
            global theFiles;
            global myFolder;
                    
            if(currentFileNumber == 1)
                file1 = fullfile(myFolder, theFiles(numberOfFiles).name);
            info = dicominfo(file1);  
            I = dicomread(info);
            imshow(I,[],'Parent', app.UIAxes);
            
            currentFileNumber = numberOfFiles; 
            else 
            file1 = fullfile(myFolder, theFiles(currentFileNumber).name);
            info = dicominfo(file1);  
            I = dicomread(info);
            imshow(I,[],'Parent', app.UIAxes);
            currentFileNumber = currentFileNumber-1;
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,currentFileNumber,length(theFiles));
  
  drawnow;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global currentFileNumber;
            global numberOfFiles;
            global theFiles;
            global myFolder;
                    
            if(currentFileNumber == numberOfFiles)
                file1 = fullfile(myFolder, theFiles(1).name);
            info = dicominfo(file1);  
            I = dicomread(info);
            imshow(I,[],'Parent', app.UIAxes);
            
            currentFileNumber = 1; 
            
            else 
            file1 = fullfile(myFolder, theFiles(currentFileNumber).name);
            info = dicominfo(file1);  
            I = dicomread(info);
            imshow(I,[],'Parent', app.UIAxes);
            currentFileNumber = currentFileNumber+1;
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,currentFileNumber,length(theFiles));
  
  drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.LoadimagesButton = uibutton(app.UIFigure, 'push');
            app.LoadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesButtonPushed, true);
            app.LoadimagesButton.Text = 'Load images';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '1/100';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end